classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        OtherfeatureButton  matlab.ui.control.Button
        TranslationButton   matlab.ui.control.Button
        RotateButton        matlab.ui.control.Button
        SaveDatasetButton   matlab.ui.control.Button
        LoadImageButton     matlab.ui.control.Button
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Stores the loaded image
        dataset % Stores the transformed images
       
    end
    
    methods (Access = private)
        
        function startupFcn(app)
            set(app.UIAxes, 'Visible', 'off');
            set(app.UIAxes_2, 'Visible', 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.tif;*.jpg;*.png;*.bmp', 'Image Files (*.tif; *.jpg, *.png, *.bmp)'}, 'Select an image');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            app.image = imread(fullfile(pathname, filename));
            imshow(app.image, 'Parent', app.UIAxes);
             set(app.UIAxes, 'Visible', 'off');
            set(app.UIAxes_2, 'Visible', 'off');
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            angle = 45; % Specify the rotation angle
            transformedImage = imrotate(app.image, angle);
            imshow(transformedImage, 'Parent', app.UIAxes_2);
            app.dataset{end+1} = transformedImage;
        end

        % Button pushed function: SaveDatasetButton
        function SaveDatasetButtonPushed(app, event)
            if isempty(app.dataset)
                msgbox('Dataset is empty. Please apply some transformations before saving.', 'Error', 'error');
                return;
            end
    
            [filename, pathname] = uiputfile('dataset.mat', 'Save Dataset');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
    
            app.dataset = app.dataset;
            save(fullfile(pathname, filename), 'dataset');
            msgbox('Dataset saved successfully.', 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Normal Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transformed image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveDatasetButton = uibutton(app.UIFigure, 'push');
            app.SaveDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatasetButtonPushed, true);
            app.SaveDatasetButton.Text = 'Save Dataset';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.TranslationButton = uibutton(app.UIFigure, 'push');
            app.TranslationButton.Text = 'Translation';
            app.OtherfeatureButton = uibutton(app.UIFigure, 'push');
            app.OtherfeatureButton.Text = 'Other feature';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end